classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DmmerungEditField             matlab.ui.control.NumericEditField
        DmmerungEditFieldLabel        matlab.ui.control.Label
        tglicheSonnenstundenistEditField  matlab.ui.control.NumericEditField
        tglicheSonnenstundenistEditFieldLabel  matlab.ui.control.Label
        SpeichernundbernehmenButton   matlab.ui.control.StateButton
        obererGrenzwertistEditField   matlab.ui.control.NumericEditField
        obererGrenzwertistEditFieldLabel  matlab.ui.control.Label
        untererGrenzwertistEditField  matlab.ui.control.NumericEditField
        untererGrenzwertistEditFieldLabel  matlab.ui.control.Label
        sLabel                        matlab.ui.control.Label
        mLabel                        matlab.ui.control.Label
        H20Break                      matlab.ui.control.NumericEditField
        ZeitdauerBewaesserungEditFieldLabel  matlab.ui.control.Label
        Height                        matlab.ui.control.NumericEditField
        BehlterhoeheEditFieldLabel    matlab.ui.control.Label
        l_Intens                      matlab.ui.control.Slider
        DmmerungSliderLabel           matlab.ui.control.Label
        l_Time                        matlab.ui.control.Slider
        tglicheSonnenstundenLabel     matlab.ui.control.Label
        SonneLichtLabel               matlab.ui.control.Label
        BewsserungLabel               matlab.ui.control.Label
        maxMoist                      matlab.ui.control.Slider
        obererGrenzwertLabel          matlab.ui.control.Label
        FeuchtigkeitLabel             matlab.ui.control.Label
        minMoist                      matlab.ui.control.Slider
        untererGrenzwertLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SpeichernundbernehmenButton
        function SpeichernundbernehmenButtonValueChanged(app, event)
            %Description: GUI for parameters of device
            %Author: Beat Peter
            %Test: 2023-02-20
            %Version: 1.0

            oldDir=pwd;

            % Variablen definieren
            filenames=["minMoist.txt" "maxMoist.txt" "Height.txt" "l_Intens.txt" "l_Time.txt" "H20Break.txt" ];
            
            %directory for file save
            cd(uigetdir)

            %Create all files with values from slider
            fileID=fopen(filenames(1),"w");
            fprintf(fileID,string(app.minMoist.Value));
            fclose(fileID);

            fileID=fopen(filenames(2),"w");
            fprintf(fileID,string(app.maxMoist.Value));
            fclose(fileID);

            fileID=fopen(filenames(3),"w");
            fprintf(fileID,string(app.Height.Value));
            fclose(fileID);

            fileID=fopen(filenames(4),"w");
            fprintf(fileID,string(app.l_Intens.Value));
            fclose(fileID);

            fileID=fopen(filenames(5),"w");
            fprintf(fileID,string(app.l_Time.Value));
            fclose(fileID);

            fileID=fopen(filenames(6),"w");
            fprintf(fileID,string(app.H20Break.Value));
            fclose(fileID);

            %Go back to original directory
            cd(oldDir);

        end

        % Value changing function: minMoist
        function minMoistValueChanging(app, event)
            changingValue = round(event.Value,0);
            app.untererGrenzwertistEditField.Value=changingValue;
            
        end

        % Value changing function: maxMoist
        function maxMoistValueChanging(app, event)
            changingValue = round(event.Value,0);
            app.obererGrenzwertistEditField.Value=changingValue;
            
        end

        % Value changing function: l_Time
        function l_TimeValueChanging(app, event)
            changingValue = round(event.Value,0);
            app.tglicheSonnenstundenistEditField.Value=changingValue;
            
        end

        % Value changing function: l_Intens
        function l_IntensValueChanging(app, event)
            changingValue = round(event.Value,0);
            app.DmmerungEditField.Value=changingValue;
        end

        % Value changed function: Height
        function HeightValueChanged(app, event)
            value = app.Height.Value;
            
        end

        % Value changed function: H20Break
        function H20BreakValueChanged(app, event)
            value = app.H20Break.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.untererGrenzwertLabel = uilabel(app.UIFigure);
            app.untererGrenzwertLabel.Text = {'unterer '; 'Grenzwert'};
            app.minMoist = uislider(app.UIFigure);
            app.minMoist.ValueChangingFcn = createCallbackFcn(app, @minMoistValueChanging, true);
            app.FeuchtigkeitLabel = uilabel(app.UIFigure);
            app.FeuchtigkeitLabel.Text = 'Feuchtigkeit [%]';
            app.obererGrenzwertLabel = uilabel(app.UIFigure);
            app.obererGrenzwertLabel.Text = {'oberer '; 'Grenzwert'};
            app.maxMoist = uislider(app.UIFigure);
            app.maxMoist.ValueChangingFcn = createCallbackFcn(app, @maxMoistValueChanging, true);
            app.BewsserungLabel = uilabel(app.UIFigure);
            app.BewsserungLabel.Text = 'Bewässerung';
            app.SonneLichtLabel = uilabel(app.UIFigure);
            app.SonneLichtLabel.Text = 'Sonne / Licht';
            app.tglicheSonnenstundenLabel = uilabel(app.UIFigure);
            app.tglicheSonnenstundenLabel.Text = {'tägliche '; 'Sonnenstunden'};
            app.l_Time = uislider(app.UIFigure);
            app.l_Time.Limits = [0 24];
            app.l_Time.ValueChangingFcn = createCallbackFcn(app, @l_TimeValueChanging, true);
            app.DmmerungSliderLabel = uilabel(app.UIFigure);
            app.DmmerungSliderLabel.Text = 'Dämmerung';
            app.l_Intens = uislider(app.UIFigure);
            app.l_Intens.Limits = [0 20];
            app.l_Intens.ValueChangingFcn = createCallbackFcn(app, @l_IntensValueChanging, true);
            app.BehlterhoeheEditFieldLabel = uilabel(app.UIFigure);
            app.BehlterhoeheEditFieldLabel.Text = 'Behälterhoehe';
            app.Height = uieditfield(app.UIFigure, 'numeric');
            app.Height.ValueChangedFcn = createCallbackFcn(app, @HeightValueChanged, true);
            app.ZeitdauerBewaesserungEditFieldLabel = uilabel(app.UIFigure);
            app.ZeitdauerBewaesserungEditFieldLabel.Text = {'Zeitdauer'; 'Bewaesserung'};
            app.H20Break = uieditfield(app.UIFigure, 'numeric');
            app.H20Break.ValueChangedFcn = createCallbackFcn(app, @H20BreakValueChanged, true);
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.untererGrenzwertistEditFieldLabel = uilabel(app.UIFigure);
            app.untererGrenzwertistEditFieldLabel.Text = 'unterer Grenzwert ist';
            app.untererGrenzwertistEditField = uieditfield(app.UIFigure, 'numeric');
            app.obererGrenzwertistEditFieldLabel = uilabel(app.UIFigure);
            app.obererGrenzwertistEditFieldLabel.Text = 'oberer Grenzwert ist';
            app.obererGrenzwertistEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeichernundbernehmenButton = uibutton(app.UIFigure, 'state');
            app.SpeichernundbernehmenButton.ValueChangedFcn = createCallbackFcn(app, @SpeichernundbernehmenButtonValueChanged, true);
            app.SpeichernundbernehmenButton.Text = 'Speichern und übernehmen';
            app.tglicheSonnenstundenistEditFieldLabel = uilabel(app.UIFigure);
            app.tglicheSonnenstundenistEditFieldLabel.Text = 'tägliche Sonnenstunden ist';
            app.tglicheSonnenstundenistEditField = uieditfield(app.UIFigure, 'numeric');
            app.DmmerungEditFieldLabel = uilabel(app.UIFigure);
            app.DmmerungEditFieldLabel.Text = 'Dämmerung';
            app.DmmerungEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end